classdef PCATrim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_4          matlab.ui.control.Label
        UpField          matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        DownField        matlab.ui.control.NumericEditField
        ResetButton      matlab.ui.control.Button
        OKButton         matlab.ui.control.Button
        TrimmingButton   matlab.ui.control.Button
        TrimerangeUp     matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        TrimerangeDown   matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        AxesButtonGroup  matlab.ui.container.ButtonGroup
        zButton          matlab.ui.control.RadioButton
        yButton          matlab.ui.control.RadioButton
        xButton          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp
        TrimmerAxis
        trimedfig
        importPoint
        Point
        fixedPoint
        convMatrix % 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, point, convMatrix)
            app.CallingApp = mainapp;
            app.Point = point;
            app.importPoint = point;
            app.TrimmerAxis =  'x';
            app.convMatrix = convMatrix;
            
            pcshowWithAxes(app.Point)
        end

        % Button pushed function: TrimmingButton
        function TrimmingButtonPushed(app, event)
            close all;
            trimerangeDown = app.TrimerangeDown.Value;
            trimerangeUp = app.TrimerangeUp.Value;
            if trimerangeUp == trimerangeDown
                uialert(uifigure, message, 'Choose Nothing');
                return;
            end

            switch app.TrimmerAxis 
                case 'x'
                    app.Point = app.Point(app.Point(:,1) > trimerangeDown & trimerangeUp > app.Point(:,1), :);
                case 'y'
                    app.Point = app.Point(app.Point(:,2) > trimerangeDown & trimerangeUp > app.Point(:,2), :);
                case 'z'
                    app.Point = app.Point(app.Point(:,3) > trimerangeDown & trimerangeUp > app.Point(:,3), :);
            end
            [app.trimedfig, coeff, app.fixedPoint] = drawPCaxis3D(app.Point); 
            app.trimedfig = scorePlot(app.fixedPoint);
            app.Point = app.fixedPoint;
            app.convMatrix = app.convMatrix * coeff; 
        end

        % Selection changed function: AxesButtonGroup
        function AxesButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxesButtonGroup.SelectedObject;
            app.TrimmerAxis = selectedButton.Text;
        end

        % Value changed function: TrimerangeDown
        function TrimerangeDownValueChanged(app, event)
            value = app.TrimerangeDown.Value;
            app.DownField.Value = value;
        end

        % Value changed function: TrimerangeUp
        function TrimerangeUpValueChanged(app, event)
            value = app.TrimerangeUp.Value;
            app.UpField.Value = value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close all;
            app.Point = app.importPoint;
            figure('Name', 'Import Point');
            pcshow(app.Point, 'AxesVisibility','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxesButtonGroup = uibuttongroup(app.UIFigure);
            app.AxesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxesButtonGroupSelectionChanged, true);
            app.AxesButtonGroup.Title = 'トリムする方向軸';
            app.xButton = uiradiobutton(app.AxesButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.AxesButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.AxesButtonGroup);
            app.zButton.Text = 'z';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '下端';
            app.TrimerangeDown = uieditfield(app.UIFigure, 'numeric');
            app.TrimerangeDown.ValueChangedFcn = createCallbackFcn(app, @TrimerangeDownValueChanged, true);
            app.TrimerangeDown.Value = -100;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '上端';
            app.TrimerangeUp = uieditfield(app.UIFigure, 'numeric');
            app.TrimerangeUp.ValueChangedFcn = createCallbackFcn(app, @TrimerangeUpValueChanged, true);
            app.TrimerangeUp.Value = 250;
            app.TrimmingButton = uibutton(app.UIFigure, 'push');
            app.TrimmingButton.ButtonPushedFcn = createCallbackFcn(app, @TrimmingButtonPushed, true);
            app.TrimmingButton.Text = 'Trimming';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DownField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '範囲';
            app.UpField = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'から';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCATrim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end