classdef CalCricum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaveDataButton        matlab.ui.control.Button
        PLOTButton            matlab.ui.control.Button
        setRegionButton       matlab.ui.control.Button
        ROISetButton          matlab.ui.control.Button
        CaluculateCircumBtn   matlab.ui.control.Button
        WhichdataButtonGroup  matlab.ui.container.ButtonGroup
        OriginalButton        matlab.ui.control.RadioButton
        PCAFixedButton        matlab.ui.control.RadioButton
        ViewfixedDataButton   matlab.ui.control.Button
        PCATrimmerAppButton   matlab.ui.control.Button
        AxisButtonGroup       matlab.ui.container.ButtonGroup
        SelectedAxisField     matlab.ui.control.EditField
        zButton               matlab.ui.control.RadioButton
        yButton               matlab.ui.control.RadioButton
        xButton               matlab.ui.control.RadioButton
        tEditField            matlab.ui.control.NumericEditField
        tLabel                matlab.ui.control.Label
        SlashButton           matlab.ui.control.Button
        StatusField           matlab.ui.control.EditField
        Label                 matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        FilePath              matlab.ui.control.EditField
        ImportButton          matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)      
        ptCloud     % PointCloud 
        importedfig         % 
        newPoint    %
        secondFig   %
        dt_X           %
        dt_Y           %
        dt_Z           %
        cutWidthT      %
        slashedPoint
        mainAxis
        TrimmerApp
        roi
        convexMatrix
        referPoint
        referConv
        selectedFig
        CircumData
    end
    
    methods (Access = public)
        
        function updatedata(app, convMatrix)
            app.PCATrimmerAppButton.Enable = 'on';
            disp(app.newPoint);
            app.convexMatrix = convMatrix;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatedata(app, app.convexMatrix);
            app.cutWidthT = app.tEditField.Value;
            app.mainAxis = app.SelectedAxisField.Value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            filename = uigetfile('*.stl');
            if filename == 0
                return;
            end
            app.FilePath.Value = filename;
            mesh = stlread(filename);         
            pt= mesh.Points; 
            ptData = pt.*1000;
            app.ptCloud = pointCloud(ptData);
            app.StatusField.Value = "インポート済み";
            
            % インポート状態での主成分分析、軸描写
            [app.importedfig, coeff, app.newPoint] = drawPCaxis3D(app.ptCloud.Location);
            app.convexMatrix = coeff;
            
            % 新点群での主成分分析、軸修正、描写
            app.secondFig = scorePlot(app.newPoint);
            hold off;
            app.StatusField.Value = "点を選択してください";

            % 初期状態でreferPoint, referConvを設定
            app.referPoint = app.newPoint;
            app.referConv = app.convexMatrix;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            close all;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            app.dt_X = 0; app.dt_Y = 0; app.dt_Z = 0;
            app.cutWidthT = 0.5;
            app.areaEditField.Value = 0;
            app.FilePath.Value = '';
            app.ptCloud = '';
            app.CircumData = '';
            app.XEditField.Value = 0; app.YEditField.Value = 0; app.ZEditField.Value = 0;
            app.AxisButtonGroup.SelectedObject = app.xButton;
            app.WhichdataButtonGroup.SelectedObject = app.PCAFixedButton;
            app.PCATrimmerAppButton.Enable = 'on';
        end

        % Button pushed function: SlashButton
        function SlashButtonPushed(app, event)
%             app.cutWidthT = app.tEditField.Value;
            if app.xButton.Value == true
                app.mainAxis = 'x';
                dt = app.dt_X;
            elseif app.yButton.Value == true
                app.mainAxis = 'y';
                dt = app.dt_Y;
            elseif app.zButton.Value == true
                app.mainAxis = 'z';
                dt = app.dt_Z;
            end
            app.slashedPoint = slashPoint(app.newPoint.Location, app.cutWidthT, dt, app.mainAxis);
            disp(app.slashedPoint);
        end

        % Selection changed function: AxisButtonGroup
        function AxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisButtonGroup.SelectedObject;
            app.mainAxis = selectedButton.Text;
            app.SelectedAxisField.Value = selectedButton.Text;
        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            point = app.referPoint;
            conv = app.referConv;
            app.PCATrimmerAppButton.Enable = 'off';
            app.TrimmerApp = secondapp(app, point, conv);
        end

        % Button pushed function: ViewfixedDataButton
        function ViewfixedDataButtonPushed(app, event)
            close all;
            newP = app.ptCloud.Location * app.convexMatrix;
%             disp(newP);
            app.newPoint = pointCloud(newP);
            app.secondFig = figure("Name","new Point View");
            hold on; grid on;
            pcshow(app.newPoint, 'AxesVisibility','on');
            hold off;          
        end

        % Selection changed function: WhichdataButtonGroup
        function WhichdataButtonGroupSelectionChanged(app, event)
            selectedButton = app.WhichdataButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'PCA-Fixed'
                    app.referPoint = app.newPoint;
                    app.referConv = app.convexMatrix;
                case 'Original'
                    app.referPoint = app.ptCloud.Location;
                    app.referConv = eye(3);
            end

        end

        % Button pushed function: CaluculateCircumBtn
        function CaluculateCircumBtnPushed(app, event)
            sPoint = app.newPoint;
            app.CircumData = '';
            app.CircumData = CalculateCircum(sPoint, app.mainAxis, app.cutWidthT);
            dt = app.CircumData(:,1);
            circum = app.CircumData(:,2);
            area = app.CircumData(:,3);
            plot(app.UIAxes, dt, circum);
            xlabel(app.UIAxes, 'dt'); ylabel(app.UIAxes, 'Circum');
            
            plot(app.UIAxes2,dt, area, '.-');

        end

        % Button pushed function: ROISetButton
        function ROISetButtonPushed(app, event)
            ax = gca;
            app.roi = images.roi.Cuboid(ax, 'Rotatable','all', 'Color','r');
            draw(app.roi);
        end

        % Button pushed function: setRegionButton
        function setRegionButtonPushed(app, event)
            selectedPoint = roiTrimmerPoints(app.newPoint, app.roi);

            app.selectedFig = figure('Name', 'Selected Region');
            pcshow(selectedPoint);
            app.newPoint = selectedPoint;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            app.cutWidthT = value;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            try 
                pcwrite(app.newPoint, 'fixedata');
            catch
                pcwrite(app.newPoint.Location);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Circum')
            xlabel(app.UIAxes, 'dt')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Area')
            xlabel(app.UIAxes2, 'dt')
            ylabel(app.UIAxes2, 'area')
            zlabel(app.UIAxes2, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilePath = uieditfield(app.UIFigure, 'text');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ステータス';
            app.StatusField = uieditfield(app.UIFigure, 'text');
            app.StatusField.Placeholder = 'Status';
            app.SlashButton = uibutton(app.UIFigure, 'push');
            app.SlashButton.ButtonPushedFcn = createCallbackFcn(app, @SlashButtonPushed, true);
            app.SlashButton.Text = 'Slash';
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = 'カット区間：t';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.tEditField.Value = 0.5;
            app.AxisButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisButtonGroupSelectionChanged, true);
            app.AxisButtonGroup.Title = 'Axis';
            app.xButton = uiradiobutton(app.AxisButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.AxisButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.AxisButtonGroup);
            app.zButton.Text = 'z';
            app.SelectedAxisField = uieditfield(app.AxisButtonGroup, 'text');
            app.SelectedAxisField.Value = 'x';
            app.PCATrimmerAppButton = uibutton(app.UIFigure, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'PCA TrimmerApp';
            app.ViewfixedDataButton = uibutton(app.UIFigure, 'push');
            app.ViewfixedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewfixedDataButtonPushed, true);
            app.ViewfixedDataButton.Text = 'View fixed Data';
            app.WhichdataButtonGroup = uibuttongroup(app.UIFigure);
            app.WhichdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhichdataButtonGroupSelectionChanged, true);
            app.WhichdataButtonGroup.Title = 'Which data';
            app.PCAFixedButton = uiradiobutton(app.WhichdataButtonGroup);
            app.PCAFixedButton.Text = 'PCA-Fixed';
            app.PCAFixedButton.Value = true;
            app.OriginalButton = uiradiobutton(app.WhichdataButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.CaluculateCircumBtn = uibutton(app.UIFigure, 'push');
            app.CaluculateCircumBtn.ButtonPushedFcn = createCallbackFcn(app, @CaluculateCircumBtnPushed, true);
            app.CaluculateCircumBtn.Text = 'Cal';
            app.ROISetButton = uibutton(app.UIFigure, 'push');
            app.ROISetButton.ButtonPushedFcn = createCallbackFcn(app, @ROISetButtonPushed, true);
            app.ROISetButton.Text = 'ROI  Set';
            app.setRegionButton = uibutton(app.UIFigure, 'push');
            app.setRegionButton.ButtonPushedFcn = createCallbackFcn(app, @setRegionButtonPushed, true);
            app.setRegionButton.Text = 'set Region';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalCricum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end