classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataManegementPanel             matlab.ui.container.Panel
        SaveName                        matlab.ui.control.EditField
        modelSave                       matlab.ui.control.Button
        filenameField                   matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PostureTab                      matlab.ui.container.Tab
        AutoOrManual                    matlab.ui.container.ButtonGroup
        Manual                          matlab.ui.control.RadioButton
        Auto                            matlab.ui.control.RadioButton
        ManualPosturePanel              matlab.ui.container.Panel
        ManualPosOKButton               matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        RotationBtn                     matlab.ui.control.Button
        DataSelection                   matlab.ui.container.ButtonGroup
        LegDirAlreadyFixed              matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        Original                        matlab.ui.control.RadioButton
        DegreeField                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        rotateAxes                      matlab.ui.container.ButtonGroup
        rotzBtn                         matlab.ui.control.RadioButton
        rotyBtn                         matlab.ui.control.RadioButton
        rotxBtn                         matlab.ui.control.RadioButton
        PostureControlPanel             matlab.ui.container.Panel
        DoneTrimBtn                     matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        ResetPostureButton              matlab.ui.control.Button
        TrimmerRangeDown                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TrimmerRangeUp                  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TrimDirectionSelectButtonGroup  matlab.ui.container.ButtonGroup
        zButton_2                       matlab.ui.control.RadioButton
        yButton_2                       matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        fixLegAndHoofTipDirectionBtn    matlab.ui.control.Button
        PCATrimmerAppButton             matlab.ui.control.Button
        CalculateTab                    matlab.ui.container.Tab
        resultApp                       matlab.ui.container.Panel
        SaveDataBtn                     matlab.ui.control.Button
        BWLength                        matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        CircumLength                    matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        CalculationDirectionAxes        matlab.ui.container.ButtonGroup
        zButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        xButton                         matlab.ui.control.RadioButton
        ManualCalPanel                  matlab.ui.container.Panel
        HowtoChoose                     matlab.ui.container.ButtonGroup
        ROIselection                    matlab.ui.control.RadioButton
        OnePoint                        matlab.ui.control.RadioButton
        ROISelectionPanel               matlab.ui.container.Panel
        ResetCalROIBtn                  matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        ClipingWithROI                  matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        OnePointSelection               matlab.ui.container.Panel
        getPositionBtn                  matlab.ui.control.Button
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ReChooseBtn                     matlab.ui.control.Button
        CalculationManualy              matlab.ui.control.Button
        AutoOrManualCal                 matlab.ui.container.ButtonGroup
        ManualCal                       matlab.ui.control.RadioButton
        AutoCal                         matlab.ui.control.RadioButton
        AutoCalPanel                    matlab.ui.container.Panel
        SamplingWidth                   matlab.ui.control.Spinner
        Label_7                         matlab.ui.control.Label
        CaluculationAutoBtn             matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SaveDataTab                     matlab.ui.container.Tab
        dateMode                        matlab.ui.container.ButtonGroup
        DataAuto                        matlab.ui.control.RadioButton
        DataOnePoint                    matlab.ui.control.RadioButton
        DataRoi                         matlab.ui.control.RadioButton
        DataModeField                   matlab.ui.control.EditField
        EditField_2Label_2              matlab.ui.control.Label
        FileNameField                   matlab.ui.control.EditField
        Label_10                        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CircumField                     matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        HoofLengthField                 matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        SaveBtn                         matlab.ui.control.Button
        DateField                       matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        FileField                       matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        ResetAllButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ptCloud % Original Data
        importedfig % 
        newPoint    %　位置合わせの点群データ変数 pointCloud
        fixedPoint % 位置合わせ時の点群データ xyzPoints
        AfterPcaPoint % PCA処理完了後の点群データ
        ManualyPoint % 手動で動かす点群
        RotatePoint % 手動で回転させた点群
        RotateObj % 回転させるOBJ
        RotAxes
        RotTheta
        secondFig   %　
        cutWidth      %　切る幅
        cutAxes
        convexMatrix % 回転行列
        trimAxes
        trimmerRangeUp
        trimmerRangeDown
        trimedfig
        secondfig
        sel_X
        sel_Y
        sel_Z
        XData
        YData
        ZData
        PointSetX
        PointSetY
        PointSetZ
        AtCircumPt
        Circum % 球節周囲長
        BackWallLength % 背壁長
        roi
        selectedPoint
        selectedFig
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            app.cutWidth = app.TEditField.Value;
            app.trimAxes = app.TrimDirectionSelectButtonGroup.SelectedObject.Text;
            app.cutAxes = app.CalculationDirectionAxes.SelectedObject.Text;
            app.ManualPosturePanel.Enable = "off";
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            filename = uigetfile({'*.stl';'*.ply'});
            DoShow = false;
            
            try
                app.ptCloud = import3Dmodel(filename, DoShow);
            catch 
                app.filenameField.Value = 'モデルはまだインポートされてません';
                return;
            end
            app.filenameField.Value = filename;
            
             % インポート状態での主成分分析、軸描写
%             [app.importedfig, coeff, app.fixedPoint] = drawPCaxis3D(app.ptCloud.Location);
            [coeff, app.fixedPoint] = pca(app.ptCloud.Location);
            app.convexMatrix = coeff;
            % 新点群での主成分分析、軸修正、描写
            app.secondFig = scorePlot(app.fixedPoint);
            app.newPoint = pointCloud(app.fixedPoint);
            app.AfterPcaPoint = app.newPoint;
            hold off;
        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            close all;
            Point = app.fixedPoint;
            up = app.trimmerRangeUp;
            down = app.trimmerRangeDown;
            switch app.trimAxes 
                case 'x'
                    Point = Point(Point(:,1) > down & up > Point(:,1), :);
                case 'y'
                    Point = Point(Point(:,2) > down & up > Point(:,2), :);
                case 'z'
                    Point = Point(Point(:,3) > down & up > Point(:,3), :);
            end
            isShow = false;
            [app.trimedfig, coeff, app.fixedPoint] = drawPCaxis3D(Point, isShow); 
            app.trimedfig = scorePlot(app.fixedPoint);
            app.convexMatrix = app.convexMatrix * coeff;
            rotateHoof = app.ptCloud.Location * app.convexMatrix;
            figure("Name","PCAによる修正済み");
            pcshow(rotateHoof, "AxesVisibility","on");
            app.newPoint = pointCloud(rotateHoof);
        end

        % Value changed function: TrimmerRangeUp
        function TrimmerRangeUpValueChanged(app, event)
            value = app.TrimmerRangeUp.Value;
            app.trimmerRangeUp = value;
        end

        % Value changed function: TrimmerRangeDown
        function TrimmerRangeDownValueChanged(app, event)
            value = app.TrimmerRangeDown.Value;
            app.trimmerRangeDown = value;
        end

        % Selection changed function: TrimDirectionSelectButtonGroup
        function TrimDirectionSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrimDirectionSelectButtonGroup.SelectedObject;
            app.trimAxes = selectedButton.Text;
        end

        % Button pushed function: DoneTrimBtn
        function DoneTrimBtnButtonPushed(app, event)
            close all;
            donePCApt = app.ptCloud.Location * app.convexMatrix;
            app.fixedPoint = donePCApt;
            app.AfterPcaPoint = pointCloud(app.fixedPoint);
            pcshowWithAxes(app.AfterPcaPoint);
        end

        % Button pushed function: fixLegAndHoofTipDirectionBtn
        function fixLegAndHoofTipDirectionBtnPushed(app, event)
            tipWidth = 40;
            sp = app.fixedPoint;
            disp(sp);
            sPoint= fixLegsDirection(pointCloud(sp), app.trimAxes, app.cutWidth);
        
            sp= fixHoofTipDirection(sPoint, app.trimAxes, tipWidth, 2);
            app.newPoint = sp;
            app.fixedPoint = sp.Location;
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
              app.PostureControlPanel.Enable = true;
              app.AfterPcaPoint = '';
              app.fixedPoint = '';
              app.filenameField.Value = '';
              app.newPoint = '';
              close all;
              app.ptCloud = '';
              app.RotatePoint = '';
              app.convexMatrix = '';
              app.TabGroup.SelectedTab = app.PostureTab;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            close all;
            sp = app.newPoint;
            app.secondfig = figure("Name","new Point View");
            hold on; grid on;
            pcshow(sp, 'AxesVisibility','on');
            hold off;
            app.TabGroup.SelectedTab = app.CalculateTab;
%             app.ManualCalPanel.Enable = 'off';
        end

        % Selection changed function: AutoOrManual
        function AutoOrManualSelectionChanged(app, event)
            selectedButton = app.AutoOrManual.SelectedObject;
            switch selectedButton.Text
                case '自動'
                    app.PostureControlPanel.Enable = "on";
                    app.ManualPosturePanel.Enable = "off";
                case '手動'
                    app.PostureControlPanel.Enable = "off";
                    app.ManualPosturePanel.Enable = "on";
            end
            app.RotAxes = app.rotateAxes.SelectedObject.Text;
            app.ManualyPoint = app.ptCloud;
            try 
                pcshowWithAxes(app.ManualyPoint);
            catch 
                close all;
                return;
            end
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.cutWidth = value;
        end

        % Selection changed function: CalculationDirectionAxes
        function CalculationDirectionAxesSelectionChanged(app, event)
            selectedButton = app.CalculationDirectionAxes.SelectedObject;
            app.cutAxes = selectedButton.Text;
        end

        % Button pushed function: CaluculationAutoBtn
        function CaluculationAutoBtnPushed(app, event)
%             sPoint = app.fixedPoint;
            sPoint = app.newPoint;
            axes = app.cutAxes;
            t = app.cutWidth;
            [app.XData, app.PointSetX] = sliceData(sPoint, 'x', t);
            [app.YData, app.PointSetY] = sliceData(sPoint, 'y', t);
            [app.ZData, app.PointSetZ] = sliceData(sPoint, 'z', t);
            switch axes
                case 'x'
                    axIdx = 1;
                    FData = app.XData;
                    FPoints = app.PointSetX;
                case 'y'
                    axIdx = 2;
                    FData = app.YData;
                    FPoints = app.PointSetY;
                case 'z'
                    axIdx = 3;
                    FData = app.ZData;
                    FPoints = app.PointSetZ;
            end
            % 第一区間探索
            pos = FData(:,1);
            area = FData(:,3);
            [~, I] = max(area);
            %　蹄断面積最大位置から130のところまで
            cutData = FData(FData(:,1) > pos(I)-130 & pos(I) > FData(:,1),:);
            cPos = cutData(:,1);
            cArea = cutData(:,3);
            %　フィルター処理と微分
            n = 2;
            Wn = 0.3;
            [y1, v1, v2] = filterAnddiff(cArea, n, Wn);
            TF = islocalmin(v1, 'MaxNumExtrema',1);
            %　第二区間探索
            RecutData = cutData(cutData(:,1) < cPos(TF)-10, :);
            rPos = RecutData(:,1);
            rArea = RecutData(:,3);

            [yr, v1_r, v2_r] = filterAnddiff(rArea);
            mTF = islocalmax(v1_r, 'MinProminence',20000, 'MaxNumExtrema',2);
            localMaxSet = [rPos(mTF) v1_r(mTF)];
            [maxPoint, I] = max(localMaxSet(:,2));
            rx_extream = localMaxSet(I);
            disp(localMaxSet(I,2));
            ax1 = app.UIAxes;
            ax2 = app.UIAxes2;
            plot(ax1, rPos, yr);
            plot(ax2, rPos, v1_r, 'b', rPos(mTF), v1_r(mTF), 'ro');
             
            sampleWidth = app.SamplingWidth.Value;
            samplemin = rx_extream-sampleWidth;
            samplemax = rx_extream;
%             sampleData = RecutData(RecutData(:,1) > samplemin & samplemax > RecutData(:,1),:);
            index = FData(:,1) > samplemin & samplemax > FData(:,1);
            sampleData = FData(index, :);
            samplePt = FPoints(index, :);
            % 平均周囲長
            app.Circum = mean(sampleData(:,2));
            sp = app.newPoint.Location;
            samplePoint = sp(sp(:,axIdx) > samplemin & sp(:, axIdx) < samplemax, :);
            pcshowWithAxes(app.newPoint, 'Marking');
            hold on; grid on;
            pcshow(samplePoint, 'r', 'AxesVisibility','on');
            hold off;
            
            app.AtCircumPt = samplePoint;
            app.CircumLength.Value = app.Circum;
            app.BackWallLength = predictHoofBackWall(app.Circum);

            app.BWLength.Value = app.BackWallLength;

        end

        % Button pushed function: getPositionBtn
        function getPositionBtnPushed(app, event)
            try
                [x, y, z] = getDatatip(app.secondfig);
            catch 
                message = {'副蹄部の点群データチップを選んで'};
                uialert(app.UIFigure, message, 'Choose Nothing');
                return;
            end
            [app.sel_X, app.sel_Y, app.sel_Z] = makeDatatipToDouble(x, y, z); 
%             disp(app.dt_X); disp(app.dt_Y); disp(app.dt_Z);
            app.XEditField.Value = app.sel_X;
            app.YEditField.Value = app.sel_Y;
            app.ZEditField.Value = app.sel_Z;
        end

        % Button pushed function: ReChooseBtn
        function ReChooseBtnButtonPushed(app, event)
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.sel_X = 0;app.sel_Y = 0;app.sel_Z = 0;
        end

        % Selection changed function: HowtoChoose
        function HowtoChooseSelectionChanged(app, event)
            selectedButton = app.HowtoChoose.SelectedObject;
            switch selectedButton.Text
                case '領域選択'
                    app.OnePointSelection.Enable = 'off';
                    app.ROISelectionPanel.Enable = 'on';
                case '一点指定'
                    app.OnePointSelection.Enable = 'on';
                    app.ROISelectionPanel.Enable = 'off';
            end
        end

        % Button pushed function: ResetCalROIBtn
        function ResetCalROIBtnPushed(app, event)
            app.roi = '';
            close all;
            pcshowWithAxes(app.newPoint);
            ax = gca;
            app.roi = images.roi.Cuboid(ax, 'Rotatable','all', 'Color','r');
            draw(app.roi);
        end

        % Button pushed function: CalculationManualy
        function CalculationManualyButtonPushed(app, event)
            whichSelectMethod = app.HowtoChoose.SelectedObject.Text;
            switch app.cutAxes 
                case 'x'
                    dt = app.sel_X;
                    make2d = [2 3];
                    label1 = 'y';label2 = 'z';
                case 'y'
                    dt = app.sel_Y;
                    make2d = [1 3];
                    label1 = 'x';label2 = 'z';
                case 'z'
                    dt = app.sel_Z;
                    make2d = [2 3];
                    label1 = 'y'; label2 = 'z';
            end

            switch whichSelectMethod
                case '一点指定'
                    app.OnePointSelection.Enable = "on";
                    app.ROISelectionPanel.Enable = "off";
                    slashedPoint = slashPoint(app.newPoint.Location, 2.5, dt, app.cutAxes);
                    slash2d = slashedPoint(:,make2d);
                    [k, av] = convhull(slash2d);
                    slash_k = [slash2d(k,1) slash2d(k,2)];
                    ax = app.UIAxes;
                    ax2 = app.UIAxes2;
                    axis(ax2,"off");
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, slash2d(:,1), slash2d(:,2), '.');
                    plot(app.UIAxes, slash2d(k,1), slash2d(k,2));
                    ax.XLabel.String = label1;
                    ax.YLabel.String = label2;
                    ax.Title.String = '凸包結果';
                    ax.Position = [25,30,508,460];
                    hold(app.UIAxes, "off");
                    [thetaSorted_k, rhoSorted_k] = sortPointOnPolar(slash_k);
                    [x_k, y_k] = pol2cart(thetaSorted_k, rhoSorted_k);
                    len = calculateLengthForPoint([x_k y_k]);
                    atCircumPt = pointCloud(slashedPoint);
                case '領域選択'
                    app.OnePointSelection.Enable = "off";
                    app.ROISelectionPanel.Enable = "on";
                    [CircumByRoi, PointSetRoi] = sliceData(app.selectedPoint, app.cutAxes, app.cutWidth);
                    [CircumMax, I] = max(CircumByRoi(:,2));
                    atCircumPt = PointSetRoi(I);
                    len = CircumMax;
            end
            app.AtCircumPt = atCircumPt;
            app.Circum = len;
            app.CircumLength.Value = len;
            app.BackWallLength = predictHoofBackWall(app.Circum);
            app.BWLength.Value = app.BackWallLength;
            pcshowWithAxes(app.newPoint, 'Marking');
            hold on; grid on;
            pcshow(app.AtCircumPt.Location, 'r', 'AxesVisibility','on');
            hold off;
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            ax = gca;
            app.roi = images.roi.Cuboid(ax, 'Rotatable','all', 'Color','r');
            draw(app.roi);
        end

        % Button pushed function: ClipingWithROI
        function ClipingWithROIButtonPushed(app, event)
            app.selectedPoint = roiTrimmerPoints(app.newPoint, app.roi);

            app.selectedFig = figure('Name', 'Selected Region');
            pcshow(app.selectedPoint, 'AxesVisibility','on');
        end

        % Selection changed function: DataSelection
        function DataSelectionSelectionChanged(app, event)
            selectedButton = app.DataSelection.SelectedObject;
            close all;
            switch selectedButton.Text
                case 'オリジナル'
                    app.ManualyPoint = app.ptCloud;
                    pcshowWithAxes(app.ManualyPoint);
                case 'PCA済み'
                    try
                        app.ManualyPoint = app.AfterPcaPoint;
                        pcshowWithAxes(app.ManualyPoint);
                    catch
                        message = 'データがありません';
                        uialert(app.UIFigure, message, 'NO DATA');
                    end
                case '肢方向修正済み'
                    try 
                        app.ManualyPoint = app.newPoint;
                        pcshowWithAxes(app.ManualyPoint);
                    catch
                        message = 'データがありません';
                        uialert(app.UIFigure, message, 'NO DATA');
                    end
            end
        end

        % Selection changed function: rotateAxes
        function rotateAxesSelectionChanged(app, event)
            selectedButton = app.rotateAxes.SelectedObject;
            switch selectedButton.Text
                case 'x'
                    app.RotAxes = 'x';
                case 'y'
                    app.RotAxes = 'y';
                case 'z'
                    app.RotAxes = 'z';
            end
        end

        % Button pushed function: RotationBtn
        function RotationBtnButtonPushed(app, event)
            app.ManualyPoint = rotatePoint(app.ManualyPoint,app.RotTheta, app.RotAxes);
            pcshowWithAxes(app.ManualyPoint);
        end

        % Value changed function: DegreeField
        function DegreeFieldValueChanged(app, event)
            value = app.DegreeField.Value;
            app.RotTheta = deg2rad(value);
        end

        % Button pushed function: ManualPosOKButton
        function ManualPosOKButtonPushed(app, event)
            close all;
            app.newPoint = app.ManualyPoint;
            sp = app.ManualyPoint;
            app.secondfig = figure("Name","new Point View");
            hold on; grid on;
            pcshow(sp, 'AxesVisibility','on');
            hold off;
            app.TabGroup.SelectedTab = app.CalculateTab;
        end

        % Button pushed function: SaveDataBtn
        function ToSaveTabBtnPushed(app, event)
            close all;
            filename = app.filenameField.Value;
            app.FileField.Value = filename;
            dt = datetime('now');
            c = round(app.Circum, 4);
            app.DateField.Value = string(dt);
            app.CircumField.Value = c;
            app.HoofLengthField.Value = app.BackWallLength;

            app.TabGroup.SelectedTab = app.SaveDataTab;
            figure("Name","Result");
            hold on;
            pcshow(app.AtCircumPt, 'r');
            pcshow(app.newPoint, "AxesVisibility","on");
            hold off;

            app.DataModeField.Value = '自動';

%             try 
%                 cData = readtable("CircumData.xlsx");
%                 app.UITable.Data = cData;
%             catch 
%                 return;
%             end
        end

        % Button pushed function: SaveBtn
        function SaveBtnButtonPushed(app, event)
            uit = app.UITable;
            tp = uit.Data;
            
            date = string(app.DateField.Value);
            model = string(app.FileField.Value);
            circum = string(app.CircumField.Value);
            HoofLeng = string(app.BackWallLength);
            datamode = string(app.DataModeField.Value);

            T = table(date, model, circum, HoofLeng, datamode);

%             find(strcmp(date, tp(end,1)));

            Tnew = [tp;T];
            
%             savexlsx = "CircumData.xlsx";
%             writetable(T, savexlsx, 'Sheet',1);
            mat = [date, model, circum, HoofLeng, datamode];
            savexlsx = 'CircumData.xlsx';
%             writematrix(mat, savexlsx, 'Range','A2');
            writematrix(mat, savexlsx, 'WriteMode','append');
            
            uit.RowName = 'numbered';            
            uit.Data = Tnew;
            
            app.DateField.Value = '';
            app.FileField.Value = '';
            app.CircumField.Value = 0;
            app.HoofLengthField.Value = 0;
            app.DataModeField.Value = '';
            
        end

        % Selection changed function: dateMode
        function dateModeSelectionChanged(app, event)
            selectBtn = app.dateMode.SelectedObject;
            mode = selectBtn.Text;
            app.DataModeField.Value = mode;
        end

        % Selection changed function: AutoOrManualCal
        function AutoOrManualCalSelectionChanged(app, event)
            close all;
            selectedButton = app.AutoOrManualCal.SelectedObject;
            
            switch selectedButton.Text
                case '自動'   
                    app.secondfig = figure("Name","Auto Select");
                    pcshow(app.newPoint, "AxesVisibility","on");
                case '手動'
                    app.secondfig = figure("Name","Manual Select");
                    pcshow(app.newPoint, "AxesVisibility","on");
            end    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PostureTab = uitab(app.TabGroup);
            app.PostureTab.Title = '姿勢制御';
            app.PostureControlPanel = uipanel(app.PostureTab);
            app.PostureControlPanel.Title = '姿勢制御自動';
            app.PCATrimmerAppButton = uibutton(app.PostureControlPanel, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'トリミング';
            app.fixLegAndHoofTipDirectionBtn = uibutton(app.PostureControlPanel, 'push');
            app.fixLegAndHoofTipDirectionBtn.ButtonPushedFcn = createCallbackFcn(app, @fixLegAndHoofTipDirectionBtnPushed, true);
            app.fixLegAndHoofTipDirectionBtn.Text = '肢、蹄方向の修正';
            app.TrimDirectionSelectButtonGroup = uibuttongroup(app.PostureControlPanel);
            app.TrimDirectionSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrimDirectionSelectButtonGroupSelectionChanged, true);
            app.TrimDirectionSelectButtonGroup.Title = 'メイン軸';
            app.xButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.zButton_2.Text = 'z';
            app.Label = uilabel(app.PostureControlPanel);
            app.Label.Text = '上端';
            app.TrimmerRangeUp = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimmerRangeUp.ValueChangedFcn = createCallbackFcn(app, @TrimmerRangeUpValueChanged, true);
            app.Label_2 = uilabel(app.PostureControlPanel);
            app.Label_2.Text = '下端';
            app.TrimmerRangeDown = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimmerRangeDown.ValueChangedFcn = createCallbackFcn(app, @TrimmerRangeDownValueChanged, true);
            app.ResetPostureButton = uibutton(app.PostureControlPanel, 'push');
            app.ResetPostureButton.Text = 'Reset';
            app.OKButton = uibutton(app.PostureControlPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DoneTrimBtn = uibutton(app.PostureControlPanel, 'push');
            app.DoneTrimBtn.ButtonPushedFcn = createCallbackFcn(app, @DoneTrimBtnButtonPushed, true);
            app.DoneTrimBtn.Text = 'トリミング完了';
            app.ManualPosturePanel = uipanel(app.PostureTab);
            app.ManualPosturePanel.Title = '手動';
            app.rotateAxes = uibuttongroup(app.ManualPosturePanel);
            app.rotateAxes.SelectionChangedFcn = createCallbackFcn(app, @rotateAxesSelectionChanged, true);
            app.rotateAxes.Title = '回転軸';
            app.rotxBtn = uiradiobutton(app.rotateAxes);
            app.rotxBtn.Text = 'x';
            app.rotxBtn.Value = true;
            app.rotyBtn = uiradiobutton(app.rotateAxes);
            app.rotyBtn.Text = 'y';
            app.rotzBtn = uiradiobutton(app.rotateAxes);
            app.rotzBtn.Text = 'z';
            app.Label_3 = uilabel(app.ManualPosturePanel);
            app.Label_3.Text = '回転角';
            app.DegreeField = uieditfield(app.ManualPosturePanel, 'numeric');
            app.DegreeField.ValueChangedFcn = createCallbackFcn(app, @DegreeFieldValueChanged, true);
            app.DataSelection = uibuttongroup(app.ManualPosturePanel);
            app.DataSelection.SelectionChangedFcn = createCallbackFcn(app, @DataSelectionSelectionChanged, true);
            app.DataSelection.Title = 'データ選択';
            app.Original = uiradiobutton(app.DataSelection);
            app.Original.Text = 'オリジナル';
            app.Original.Value = true;
            app.PCAButton = uiradiobutton(app.DataSelection);
            app.PCAButton.Text = 'PCA済み';
            app.LegDirAlreadyFixed = uiradiobutton(app.DataSelection);
            app.LegDirAlreadyFixed.Text = '肢方向修正済み';
            app.RotationBtn = uibutton(app.ManualPosturePanel, 'push');
            app.RotationBtn.ButtonPushedFcn = createCallbackFcn(app, @RotationBtnButtonPushed, true);
            app.RotationBtn.Text = '回転';
            app.Switch = uiswitch(app.ManualPosturePanel, 'slider');
            app.Switch.Items = {'マイナス方向', 'プラス方向'};
            app.Switch.Value = 'プラス方向';
            app.ManualPosOKButton = uibutton(app.ManualPosturePanel, 'push');
            app.ManualPosOKButton.ButtonPushedFcn = createCallbackFcn(app, @ManualPosOKButtonPushed, true);
            app.ManualPosOKButton.Text = 'OK';
            app.AutoOrManual = uibuttongroup(app.PostureTab);
            app.AutoOrManual.SelectionChangedFcn = createCallbackFcn(app, @AutoOrManualSelectionChanged, true);
            app.Auto = uiradiobutton(app.AutoOrManual);
            app.Auto.Text = '自動';
            app.Auto.Value = true;
            app.Manual = uiradiobutton(app.AutoOrManual);
            app.Manual.Text = '手動';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = '計算';
            app.UIAxes = uiaxes(app.CalculateTab);
            title(app.UIAxes, '座標と断面積')
            xlabel(app.UIAxes, 'Position')
            ylabel(app.UIAxes, 'Area')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CalculateTab);
            title(app.UIAxes2, '断面積の一階微分')
            xlabel(app.UIAxes2, 'Position')
            ylabel(app.UIAxes2, 'diff_1')
            zlabel(app.UIAxes2, 'Z')
            app.AutoCalPanel = uipanel(app.CalculateTab);
            app.AutoCalPanel.Title = '周囲長計算・自動探索';
            app.CaluculationAutoBtn = uibutton(app.AutoCalPanel, 'push');
            app.CaluculationAutoBtn.ButtonPushedFcn = createCallbackFcn(app, @CaluculationAutoBtnPushed, true);
            app.CaluculationAutoBtn.Text = '自動計算';
            app.Label_7 = uilabel(app.AutoCalPanel);
            app.Label_7.Text = 'サンプリング幅';
            app.SamplingWidth = uispinner(app.AutoCalPanel);
            app.SamplingWidth.Step = 0.5;
            app.SamplingWidth.Limits = [1 10];
            app.SamplingWidth.Value = 5;
            app.AutoOrManualCal = uibuttongroup(app.CalculateTab);
            app.AutoOrManualCal.SelectionChangedFcn = createCallbackFcn(app, @AutoOrManualCalSelectionChanged, true);
            app.AutoCal = uiradiobutton(app.AutoOrManualCal);
            app.AutoCal.Text = '自動';
            app.AutoCal.Value = true;
            app.ManualCal = uiradiobutton(app.AutoOrManualCal);
            app.ManualCal.Text = '手動';
            app.ManualCalPanel = uipanel(app.CalculateTab);
            app.ManualCalPanel.Title = '周囲長計算・手動探索';
            app.CalculationManualy = uibutton(app.ManualCalPanel, 'push');
            app.CalculationManualy.ButtonPushedFcn = createCallbackFcn(app, @CalculationManualyButtonPushed, true);
            app.CalculationManualy.Text = '計算';
            app.OnePointSelection = uipanel(app.ManualCalPanel);
            app.OnePointSelection.Title = '一点指定';
            app.ReChooseBtn = uibutton(app.OnePointSelection, 'push');
            app.ReChooseBtn.ButtonPushedFcn = createCallbackFcn(app, @ReChooseBtnButtonPushed, true);
            app.ReChooseBtn.Text = '再選択';
            app.XEditFieldLabel = uilabel(app.OnePointSelection);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.YEditFieldLabel = uilabel(app.OnePointSelection);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.ZEditFieldLabel = uilabel(app.OnePointSelection);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.getPositionBtn = uibutton(app.OnePointSelection, 'push');
            app.getPositionBtn.ButtonPushedFcn = createCallbackFcn(app, @getPositionBtnPushed, true);
            app.getPositionBtn.Text = '座標選択';
            app.ROISelectionPanel = uipanel(app.ManualCalPanel);
            app.ROISelectionPanel.Title = '範囲選択';
            app.ROIButton = uibutton(app.ROISelectionPanel, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROIセット';
            app.ClipingWithROI = uibutton(app.ROISelectionPanel, 'push');
            app.ClipingWithROI.ButtonPushedFcn = createCallbackFcn(app, @ClipingWithROIButtonPushed, true);
            app.ClipingWithROI.Text = '切り出し';
            app.Button_8 = uibutton(app.ROISelectionPanel, 'push');
            app.Button_8.Text = '範囲選択';
            app.ResetCalROIBtn = uibutton(app.ROISelectionPanel, 'push');
            app.ResetCalROIBtn.ButtonPushedFcn = createCallbackFcn(app, @ResetCalROIBtnPushed, true);
            app.ResetCalROIBtn.Text = 'Reset';
            app.HowtoChoose = uibuttongroup(app.ManualCalPanel);
            app.HowtoChoose.SelectionChangedFcn = createCallbackFcn(app, @HowtoChooseSelectionChanged, true);
            app.OnePoint = uiradiobutton(app.HowtoChoose);
            app.OnePoint.Text = '一点指定';
            app.OnePoint.Value = true;
            app.ROIselection = uiradiobutton(app.HowtoChoose);
            app.ROIselection.Text = '領域選択';
            app.CalculationDirectionAxes = uibuttongroup(app.CalculateTab);
            app.CalculationDirectionAxes.SelectionChangedFcn = createCallbackFcn(app, @CalculationDirectionAxesSelectionChanged, true);
            app.CalculationDirectionAxes.Title = 'メイン計算方向軸';
            app.xButton = uiradiobutton(app.CalculationDirectionAxes);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.CalculationDirectionAxes);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.CalculationDirectionAxes);
            app.zButton.Text = 'z';
            app.TEditFieldLabel = uilabel(app.CalculateTab);
            app.TEditFieldLabel.Text = 'カット幅T';
            app.TEditField = uieditfield(app.CalculateTab, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 0.5;
            app.resultApp = uipanel(app.CalculateTab);
            app.resultApp.Title = '結果';
            app.Label_4 = uilabel(app.resultApp);
            app.Label_4.Text = '推定周囲長';
            app.CircumLength = uieditfield(app.resultApp, 'numeric');
            app.Label_6 = uilabel(app.resultApp);
            app.Label_6.Text = '推定蹄背壁長';
            app.BWLength = uieditfield(app.resultApp, 'numeric');
            app.SaveDataBtn = uibutton(app.resultApp, 'push');
            app.SaveDataBtn.ButtonPushedFcn = createCallbackFcn(app, @ToSaveTabBtnPushed, true);
            app.SaveDataBtn.Text = '保存タブへ';
            app.SaveDataTab = uitab(app.TabGroup);
            app.SaveDataTab.Title = '保存';
            app.Label_8 = uilabel(app.SaveDataTab);
            app.Label_8.Text = 'モデル名';
            app.FileField = uieditfield(app.SaveDataTab, 'text');
            app.FileField.Placeholder = '.stl';
            app.EditField_3Label = uilabel(app.SaveDataTab);
            app.EditField_3Label.Text = '日付';
            app.DateField = uieditfield(app.SaveDataTab, 'text');
            app.DateField.Placeholder = 'YYYY/MM/DD HH:MM:SS';
            app.SaveBtn = uibutton(app.SaveDataTab, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnButtonPushed, true);
            app.SaveBtn.Text = '保存';
            app.EditField_2Label = uilabel(app.SaveDataTab);
            app.EditField_2Label.Text = '推定蹄背壁長';
            app.HoofLengthField = uieditfield(app.SaveDataTab, 'numeric');
            app.Label_9 = uilabel(app.SaveDataTab);
            app.Label_9.Text = '周囲長';
            app.CircumField = uieditfield(app.SaveDataTab, 'numeric');
            app.UITable = uitable(app.SaveDataTab);
            app.UITable.ColumnName = {'日付'; 'モデル'; '周囲長'; '蹄背壁長'; '取得方法'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.Label_10 = uilabel(app.SaveDataTab);
            app.Label_10.Text = '保存ファイル名';
            app.FileNameField = uieditfield(app.SaveDataTab, 'text');
            app.FileNameField.Placeholder = 'CircumData.xlsx';
            app.FileNameField.Value = 'CircumData';
            app.EditField_2Label_2 = uilabel(app.SaveDataTab);
            app.EditField_2Label_2.Text = 'データの取得方法';
            app.DataModeField = uieditfield(app.SaveDataTab, 'text');
            app.dateMode = uibuttongroup(app.SaveDataTab);
            app.dateMode.SelectionChangedFcn = createCallbackFcn(app, @dateModeSelectionChanged, true);
            app.dateMode.Title = 'Button Group';
            app.DataRoi = uiradiobutton(app.dateMode);
            app.DataRoi.Text = '自動';
            app.DataRoi.Value = true;
            app.DataOnePoint = uiradiobutton(app.dateMode);
            app.DataOnePoint.Text = '一点指定';
            app.DataAuto = uiradiobutton(app.dateMode);
            app.DataAuto.Text = '領域選択';
            app.DataManegementPanel = uipanel(app.UIFigure);
            app.DataManegementPanel.Title = 'データ管理';
            app.ImportButton = uibutton(app.DataManegementPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import ';
            app.filenameField = uieditfield(app.DataManegementPanel, 'text');
            app.modelSave = uibutton(app.DataManegementPanel, 'push');
            app.modelSave.Text = 'モデル保存';
            app.SaveName = uieditfield(app.DataManegementPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end